classdef geometric_learning_media < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        MenuTab              matlab.ui.container.Tab
        LTTP_Button          matlab.ui.control.Button
        RF_Button            matlab.ui.control.Button
        RT_Button            matlab.ui.control.Button
        DL_Button            matlab.ui.control.Button
        TR_Button            matlab.ui.control.Button
        Menu_Image           matlab.ui.control.Image
        TRTab                matlab.ui.container.Tab
        TR_Visual3DButton    matlab.ui.control.Button
        TR_Visual2DButton    matlab.ui.control.Button
        TR_BackButton        matlab.ui.control.Button
        TR_Image             matlab.ui.control.Image
        DLTab                matlab.ui.container.Tab
        DL_Visual3DButton    matlab.ui.control.Button
        DL_Visual2DButton    matlab.ui.control.Button
        DL_BackButton        matlab.ui.control.Button
        DL_Image             matlab.ui.control.Image
        RTTab                matlab.ui.container.Tab
        RT_Visual3DButton    matlab.ui.control.Button
        RT_Visual2DButton    matlab.ui.control.Button
        RT_BackButton        matlab.ui.control.Button
        RT_Image             matlab.ui.control.Image
        RFTab                matlab.ui.container.Tab
        RF_Visual3DButton    matlab.ui.control.Button
        RF_Visual2DButton    matlab.ui.control.Button
        RF_BackButton        matlab.ui.control.Button
        RF_Image             matlab.ui.control.Image
        TR2DTab              matlab.ui.container.Tab
        Panel                matlab.ui.container.Panel
        TR2D_UIAxes          matlab.ui.control.UIAxes
        TR2D_Y_EditField     matlab.ui.control.NumericEditField
        TR2D_X_EditField     matlab.ui.control.NumericEditField
        TR2D_FY_EditField    matlab.ui.control.NumericEditField
        TR2D_FX_EditField    matlab.ui.control.NumericEditField
        TR2D_EY_EditField    matlab.ui.control.NumericEditField
        TR2D_EX_EditField    matlab.ui.control.NumericEditField
        TR2D_DY_EditField    matlab.ui.control.NumericEditField
        TR2D_DX_EditField    matlab.ui.control.NumericEditField
        TR2D_CY_EditField    matlab.ui.control.NumericEditField
        TR2D_CX_EditField    matlab.ui.control.NumericEditField
        TR2D_BY_EditField    matlab.ui.control.NumericEditField
        TR2D_BX_EditField    matlab.ui.control.NumericEditField
        TR2D_AY_EditField    matlab.ui.control.NumericEditField
        TR2D_AX_EditField    matlab.ui.control.NumericEditField
        TR2D_PlayButton      matlab.ui.control.Button
        TR2D_PlotButton      matlab.ui.control.Button
        TR2D_BackButton      matlab.ui.control.Button
        TR2D_Image           matlab.ui.control.Image
        DL2DTab              matlab.ui.container.Tab
        DL2D_DL_EditField    matlab.ui.control.NumericEditField
        DL2D_FY_EditField    matlab.ui.control.NumericEditField
        DL2D_FX_EditField    matlab.ui.control.NumericEditField
        DL2D_EY_EditField    matlab.ui.control.NumericEditField
        DL2D_EX_EditField    matlab.ui.control.NumericEditField
        DL2D_DY_EditField    matlab.ui.control.NumericEditField
        DL2D_DX_EditField    matlab.ui.control.NumericEditField
        DL2D_CY_EditField    matlab.ui.control.NumericEditField
        DL2D_CX_EditField    matlab.ui.control.NumericEditField
        DL2D_BY_EditField    matlab.ui.control.NumericEditField
        DL2D_BX_EditField    matlab.ui.control.NumericEditField
        DL2D_AY_EditField    matlab.ui.control.NumericEditField
        DL2D_AX_EditField    matlab.ui.control.NumericEditField
        DL2D_PlayButton      matlab.ui.control.Button
        DL2D_PlotButton      matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        DL2D_UIAxes          matlab.ui.control.UIAxes
        DL2D_BackButton      matlab.ui.control.Button
        DL2D_Image           matlab.ui.control.Image
        RT2DTab              matlab.ui.container.Tab
        RT2D_RT_EditField    matlab.ui.control.NumericEditField
        RT2D_FY_EditField    matlab.ui.control.NumericEditField
        RT2D_FX_EditField    matlab.ui.control.NumericEditField
        RT2D_EY_EditField    matlab.ui.control.NumericEditField
        RT2D_EX_EditField    matlab.ui.control.NumericEditField
        RT2D_DY_EditField    matlab.ui.control.NumericEditField
        RT2D_DX_EditField    matlab.ui.control.NumericEditField
        RT2D_CY_EditField    matlab.ui.control.NumericEditField
        RT2D_CX_EditField    matlab.ui.control.NumericEditField
        RT2D_BY_EditField    matlab.ui.control.NumericEditField
        RT2D_BX_EditField    matlab.ui.control.NumericEditField
        RT2D_AY_EditField    matlab.ui.control.NumericEditField
        RT2D_AX_EditField    matlab.ui.control.NumericEditField
        RT2D_PlotButton      matlab.ui.control.Button
        RT2D_PlayButton      matlab.ui.control.Button
        Panel_3              matlab.ui.container.Panel
        RT2D_UIAxes          matlab.ui.control.UIAxes
        RT2D_BackButton      matlab.ui.control.Button
        RT2D_Image           matlab.ui.control.Image
        RF2DTab              matlab.ui.container.Tab
        RF2D_K_EditField     matlab.ui.control.NumericEditField
        RF2D_TypeListBox     matlab.ui.control.ListBox
        RF2D_FY_EditField    matlab.ui.control.NumericEditField
        RF2D_FX_EditField    matlab.ui.control.NumericEditField
        RF2D_EY_EditField    matlab.ui.control.NumericEditField
        RF2D_EX_EditField    matlab.ui.control.NumericEditField
        RF2D_DY_EditField    matlab.ui.control.NumericEditField
        RF2D_DX_EditField    matlab.ui.control.NumericEditField
        RF2D_CY_EditField    matlab.ui.control.NumericEditField
        RF2D_CX_EditField    matlab.ui.control.NumericEditField
        RF2D_BY_EditField    matlab.ui.control.NumericEditField
        RF2D_BX_EditField    matlab.ui.control.NumericEditField
        RF2D_AY_EditField    matlab.ui.control.NumericEditField
        RF2D_AX_EditField    matlab.ui.control.NumericEditField
        RF2D_PlotButton      matlab.ui.control.Button
        RF2D_PlayButton      matlab.ui.control.Button
        Panel_4              matlab.ui.container.Panel
        RF2D_UIAxes          matlab.ui.control.UIAxes
        RF2D_BackButton      matlab.ui.control.Button
        RF2D_Image           matlab.ui.control.Image
        TR3DTab              matlab.ui.container.Tab
        GarisobjekButton     matlab.ui.control.StateButton
        TR3D_Z_EditField     matlab.ui.control.NumericEditField
        TR3D_Y_EditField     matlab.ui.control.NumericEditField
        TR3D_X_EditField     matlab.ui.control.NumericEditField
        TR3D_ZPos_EditField  matlab.ui.control.NumericEditField
        TR3D_YPos_EditField  matlab.ui.control.NumericEditField
        TR3D_XPos_EditField  matlab.ui.control.NumericEditField
        TR3D_SizeSlider      matlab.ui.control.Slider
        TR3D_ShapeListBox    matlab.ui.control.ListBox
        TR3D_PlayButton      matlab.ui.control.Button
        TR3D_PlotButton      matlab.ui.control.Button
        Panel_5              matlab.ui.container.Panel
        TR3D_UIAxes          matlab.ui.control.UIAxes
        TR3D_BackButton      matlab.ui.control.Button
        TR3D_Image           matlab.ui.control.Image
        DL3DTab              matlab.ui.container.Tab
        GarisobjekButton_2   matlab.ui.control.StateButton
        DL3D_DL_EditField    matlab.ui.control.NumericEditField
        DL3D_ZPos_EditField  matlab.ui.control.NumericEditField
        DL3D_XPos_EditField  matlab.ui.control.NumericEditField
        DL3D_YPos_EditField  matlab.ui.control.NumericEditField
        DL3D_SizeSlider      matlab.ui.control.Slider
        DL3D_ShapeListBox    matlab.ui.control.ListBox
        DL3D_PlayButton      matlab.ui.control.Button
        DL3D_PlotButton      matlab.ui.control.Button
        Panel_6              matlab.ui.container.Panel
        DL3D_UIAxes          matlab.ui.control.UIAxes
        DL3D_BackButton      matlab.ui.control.Button
        DL3D_Image           matlab.ui.control.Image
        RT3DTab              matlab.ui.container.Tab
        GarisobjekButton_3   matlab.ui.control.StateButton
        RT3D_RT_EditField    matlab.ui.control.NumericEditField
        RT3D_ButtonGroup     matlab.ui.container.ButtonGroup
        RT3D_ZButton         matlab.ui.control.RadioButton
        RT3D_YButton         matlab.ui.control.RadioButton
        RT3D_XButton         matlab.ui.control.RadioButton
        RT3D_ZPos_EditField  matlab.ui.control.NumericEditField
        RT3D_XPos_EditField  matlab.ui.control.NumericEditField
        RT3D_YPos_EditField  matlab.ui.control.NumericEditField
        RT3D_SizeSlider      matlab.ui.control.Slider
        RT3D_ShapeListBox    matlab.ui.control.ListBox
        RT3D_PlayButton      matlab.ui.control.Button
        RT3D_PlotButton      matlab.ui.control.Button
        Panel_7              matlab.ui.container.Panel
        RT3D_UIAxes          matlab.ui.control.UIAxes
        RT3D_BackButton      matlab.ui.control.Button
        RT3D_Image           matlab.ui.control.Image
        RF3DTab              matlab.ui.container.Tab
        GarisobjekButton_4   matlab.ui.control.StateButton
        RF3D_ButtonGroup     matlab.ui.container.ButtonGroup
        RF3D_XZButton        matlab.ui.control.RadioButton
        RF3D_YZButton        matlab.ui.control.RadioButton
        RF3D_XYButton        matlab.ui.control.RadioButton
        RF3D_ZPos_EditField  matlab.ui.control.NumericEditField
        RF3D_XPos_EditField  matlab.ui.control.NumericEditField
        RF3D_YPos_EditField  matlab.ui.control.NumericEditField
        RF3D_SizeSlider      matlab.ui.control.Slider
        RF3D_ShapeListBox    matlab.ui.control.ListBox
        RF3D_PlayButton      matlab.ui.control.Button
        RF3D_PlotButton      matlab.ui.control.Button
        Panel_8              matlab.ui.container.Panel
        RF3D_UIAxes          matlab.ui.control.UIAxes
        RF3D_BackButton      matlab.ui.control.Button
        RF3D_Image           matlab.ui.control.Image
        LTTPTab              matlab.ui.container.Tab
        LTTP_RepeatButton    matlab.ui.control.Button
        TotalLabel           matlab.ui.control.Label
        IndexLabel           matlab.ui.control.Label
        Panel_9              matlab.ui.container.Panel
        LTTP_UIAxes          matlab.ui.control.UIAxes
        LTTP_SubmitButton    matlab.ui.control.Button
        LTTP_ShapeListBox    matlab.ui.control.ListBox
        LTTP_BackButton      matlab.ui.control.Button
        LTTP_Image           matlab.ui.control.Image
        HSLTab               matlab.ui.container.Tab
        AnswerTextArea       matlab.ui.control.TextArea
        ScoreLabel           matlab.ui.control.Label
        HSL_BackButton       matlab.ui.control.Button
        HSL_Image            matlab.ui.control.Image
    end

    
    properties (Access = public)
        % General 
        PreviousTab       % Peviously selected tab
        Limit = 50        % The line limit for cartesius and reflection
        edge3D = 'none'   % Determine if edge is shown
        isAnimating = false % Tracking if the plot is still animating
        labelHandles        % For removing label

        % (not efficient but I already wrote it sooooo, meh....)
        % Plot 2D shape X and Y coordinates        
        TR2D_X_Coor         % The shape X coordinates for TR2D
        TR2D_Y_Coor         % The shape Y coordinates for TR2D
        TR2D_X_Coor_Trans   % The transformed shape X coordinates for TR2D
        TR2D_Y_Coor_Trans   % The transformed shape Y coordinates for TR2D
        TR2D_Shape          % The plotted shape in TR2D
        TR2D_SLabels        % The plotted shape labels in TR2D
        TR2D_Result         % THe plotted transformed shape in TR2D
        TR2D_RLabels        % The plotted transformed labels in TR2D

        DL2D_X_Coor         % The for shape X coordinates for DL2D
        DL2D_Y_Coor         % The for shape Y coordinates for DL2D
        DL2D_X_Coor_Trans   % The transformed shape X coordinates for DL2D
        DL2D_Y_Coor_Trans   % The transformed shape Y coordinates for DL2D
        DL2D_Shape          % The plotted shape in DL2D
        DL2D_SLabels        % The plotted shape labels in DL2D
        DL2D_Result         % THe plotted transformed shape in DL2D
        DL2D_RLabels        % The plotted transformed labels in DL2D

        RT2D_X_Coor         % The for shape X coordinates for RT2D
        RT2D_Y_Coor         % The for shape Y coordinates for RT2D
        RT2D_X_Coor_Trans   % The transformed shape X coordinates for RT2D
        RT2D_Y_Coor_Trans   % The transformed shape Y coordinates for RT2D
        RT2D_Shape          % The plotted shape in RT2D
        RT2D_SLabels        % The plotted shape labels in RT2D
        RT2D_Result         % THe plotted transformed shape in RT2D
        RT2D_RLabels        % The plotted transformed labels in RT2D

        RF2D_X_Coor         % The for shape X coordinates for RF2D
        RF2D_Y_Coor         % The for shape Y coordinates for RF2D
        RF2D_X_Coor_Trans   % The transformed shape X coordinates for RF2D
        RF2D_Y_Coor_Trans   % The transformed shape Y coordinates for RF2D
        RF2D_Shape          % The plotted shape in RF2D
        RF2D_SLabels        % The plotted shape labels in RF2D
        RF2D_Result         % THe plotted transformed shape in RF2D
        RF2D_RLabels        % The plotted transformed labels in RF2D
        RF2D_Line           % The plotted line helelper

        % Better 3D vars
        Vertices            % The 3D vertices (points)
        TransformedVertices % The transformed 3D vertices
        Faces               % The 3D faces (connecting the vertices)
        Shape_3D            % The plotted 3D shape
        Result_3D           % The plotted transformed 3D shape

        % Exercise vars
        CurrentIndex        % Current question index
        MaxExercise = 10    % Max exercise questions
        Answers             % List of answers
        Score               % Score
        Exercise_Y_Coor     % Y coor for the exercise
        Exercise_X_Coor     % X coor for the exercise
        Exercise_Y_Coor_Trans % Transformed Y coor for the exercise
        Exercise_X_Coor_Trans % Transformed X coor for the exercise
        Exercise_Value      % Exercise transformation value
        Exercise_Answer     % Current correct answer for the exercise
        Exercise_Shape      % Exercise shape
        Exercise_SLabels    % Exercise shape labes
        Exercise_Result     % Exercise transformed shape
        Exercise_RLabels    % Exercise transformed shape labels

        % Results vars

    end
    
    methods (Access = private)

        % General UI functions
        function changeTab(app, Tab)
            % Also clear the var if exit
            clearCoor(app)

            % Resets edge state button
            edgeButton(app)

            app.PreviousTab = app.TabGroup.SelectedTab;
            app.TabGroup.SelectedTab = Tab;
        end

        function clearCoor(app)
            % Clear the previously saved coors
            % The shape and result are cleared with init cla()

            app.TR2D_X_Coor = [];
            app.TR2D_Y_Coor = []; 
            app.TR2D_X_Coor_Trans = []; 
            app.TR2D_Y_Coor_Trans = [];

            app.DL2D_X_Coor = [];
            app.DL2D_Y_Coor = []; 
            app.DL2D_X_Coor_Trans = []; 
            app.DL2D_Y_Coor_Trans = [];

            app.RT2D_X_Coor = [];
            app.RT2D_Y_Coor = []; 
            app.RT2D_X_Coor_Trans = []; 
            app.RT2D_Y_Coor_Trans = [];

            app.RF2D_X_Coor = [];
            app.RF2D_Y_Coor = []; 
            app.RF2D_X_Coor_Trans = []; 
            app.RF2D_Y_Coor_Trans = [];

            app.Vertices = [];
            app.TransformedVertices = [];
            app.Faces = [];          
        end
        
        function edgeButton(app)
            if strcmp(app.edge3D, 'none')
                state = 0;
            else
                state = 1;
            end

            app.GarisobjekButton.Value = state;
            app.GarisobjekButton_2.Value = state;
            app.GarisobjekButton_3.Value = state;
            app.GarisobjekButton_4.Value = state;
        end

        % 2D Functions
        function init2DGraph(app, UIAxes)
            cla(UIAxes);
            hold(UIAxes, 'on');

            % Set tick and line to the limit value
            UIAxes.YTick = -app.Limit:1:app.Limit;
            UIAxes.XTick = -app.Limit:1:app.Limit;

            plot(UIAxes, [-app.Limit, app.Limit], [0, 0], LineWidth=2, Color='black')
            plot(UIAxes, [0, 0], [-app.Limit, app.Limit], LineWidth=2, Color='black')
        end
        
        function [h, labelHandles] = plot2DGraph(~, UIAxes, x_coor, y_coor, color, useLabel, useApostrophe)
            % Plot the graph
            h = plot(UIAxes, x_coor, y_coor, 'Color', color, 'LineWidth', 2);
        
            % Initialize labelHandles to an empty array
            labelHandles = [];
        
            % Exclude the last coordinate for labeling
            numLabels = length(x_coor) - 1;
        
            % Generate labels with or without apostrophe
            if useApostrophe
                labels = arrayfun(@(i) [char('A' + (i - 1)), ''''], 1:numLabels, 'UniformOutput', false);
            else
                labels = arrayfun(@(i) char('A' + (i - 1)), 1:numLabels, 'UniformOutput', false);
            end
        
            % Add point labels dynamically
            if useLabel
                labelHandles = gobjects(numLabels, 1); % Preallocate handles for labels
                hold(UIAxes, 'on'); % Retain the plot when adding labels
                for i = 1:numLabels
                    labelHandles(i) = text(UIAxes, x_coor(i), y_coor(i), labels{i}, 'HorizontalAlignment', 'center', ...
                                           'VerticalAlignment', 'bottom', 'FontSize', 15, 'FontWeight', 'bold', ...
                                           'Color', color);
                end
            end
        end

        function [x_result, y_result] = calculate2DTrasnformation( ...
                ~, x_shape, y_shape, transformationType, transformationValue)

            % Prevent transformation error if the coors is not 
            % initialized yet.
            if isempty(x_shape) | isempty(y_shape)
                x_result = [];
                y_result = [];
                return
            end          

            x_transformed = x_shape;
            y_transformed = y_shape;
            
            switch transformationType
                case 'translate'
                    translationVector = transformationValue; % [dx, dy]
                    x_result = x_transformed + translationVector(1);
                    y_result = y_transformed + translationVector(2);
        
                case 'rotate'
                    theta = transformationValue; % Rotation angle
                    theta_rad = deg2rad(theta);
                    rotationMatrix = [cos(theta_rad), -sin(theta_rad); sin(theta_rad), cos(theta_rad)];
                    coords = rotationMatrix * [x_transformed; y_transformed];
                    x_result = coords(1, :);
                    y_result = coords(2, :);
        
                 case 'reflect'
                    if ischar(transformationValue) % Single value reflection
                        if strcmp(transformationValue, 'o')
                            x_result = -x_transformed; % Reflect over origin
                            y_result = -y_transformed; 
                        elseif strcmp(transformationValue, 'x')
                            x_result = x_transformed;  
                            y_result = -y_transformed; 
                        elseif strcmp(transformationValue, 'y')
                            x_result = -x_transformed; 
                            y_result = y_transformed;  
                        else
                            x_result = x_transformed;
                            y_result = y_transformed;
                        end
                    elseif iscell(transformationValue) % Cell array for more complex reflections
                        if strcmp(transformationValue{1}, 'yline')
                            if strcmp(transformationValue{2}, 'x')
                                x_result = y_transformed;  
                                y_result = x_transformed;  
                            elseif strcmp(transformationValue{2}, '-x')
                                x_result = -y_transformed;  
                                y_result = -x_transformed;  
                            else
                                y_line = transformationValue{2}; % Reflect over line y = k
                                x_result = x_transformed;       
                                y_result = 2*y_line - y_transformed; 
                            end
                        elseif strcmp(transformationValue{1}, 'xline')
                            x_line = transformationValue{2}; % Reflect over line x = h
                            x_result = 2*x_line - x_transformed; 
                            y_result = y_transformed;       
                        else
                            x_result = x_transformed;
                            y_result = y_transformed;
                        end
                    end
        
                case 'dilate'
                    scaleFactor = transformationValue; % Scale factor
                    x_result = x_transformed * scaleFactor;
                    y_result = y_transformed * scaleFactor;
            end
        end

        function [h_result, labelHandles] = animate2DTransformation(app, UIAxes, x_shape, y_shape, ...
                x_result, y_result)

                % Framerate
                num_steps = 60;
                pause_time = 1/60;
            
                x_difference = (x_result - x_shape) / num_steps;
                y_difference = (y_result - y_shape) / num_steps;
                
                app.isAnimating = true;
                for step = 1:num_steps
                    % Calculate and plote intermidiate steps
                    x_interp = x_shape + step * x_difference;
                    y_interp = y_shape + step * y_difference;
            
                    [h] = plot2DGraph(app, UIAxes, x_interp, y_interp, 'red', false, true);
                    pause(pause_time);           
                    delete(h)
                end
                app.isAnimating = false;

                [h_result, labelHandles]  = plot2DGraph(app, UIAxes, x_result, y_result, 'red', true, true);
        end

        function value = reflectionHelper(app, k, type)
            % Determine the value based on the type
            switch type
                case 1
                    value = 'o';               
                case 2
                    value = 'x';        
                case 3
                    value = 'y';        
                case 4
                    value = {'yline', 'x'};        
                case 5
                    value = {'yline', '-x'};        
                case 6
                    value = {'xline', k};        
                case 7
                    value = {'yline', k};
            end
            
            % Plot the line based on value, if it's a line type
            if iscell(value)
                if strcmp(value{1}, 'xline') && isnumeric(value{2}) % Vertical line x = k
                    x_coor = ones(1, 101) * k;
                    y_coor = linspace(-app.Limit, app.Limit, 101);
                elseif strcmp(value{1}, 'yline') && isnumeric(value{2}) % Horizontal line y = k
                    x_coor = linspace(-app.Limit, app.Limit, 101);
                    y_coor = ones(1, 101) * k;
                elseif strcmp(value{1}, 'yline') && strcmp(value{2}, 'x') % Diagonal y = x
                    x_coor = linspace(-app.Limit, app.Limit, 101);
                    y_coor = x_coor; % Since y = x
                elseif strcmp(value{1}, 'yline') && strcmp(value{2}, '-x') % Diagonal y = -x
                    x_coor = linspace(-app.Limit, app.Limit, 101);
                    y_coor = -x_coor; % Since y = -x
                end
                % Plot the line in green
                app.RF2D_Line = plot2DGraph(app, app.RF2D_UIAxes, x_coor, y_coor, 'green', false, false);
            end
        end
        
        % 3D Functions
        function init3dGraph(app, UIAxes)
            cla(UIAxes);
            hold(UIAxes, 'on');

            % Set tick and line to the limit value
            axisRange = [-app.Limit app.Limit];
            UIAxes.YTick = -app.Limit:1:app.Limit;
            UIAxes.XTick = -app.Limit:1:app.Limit;
            UIAxes.ZTick = -app.Limit:1:app.Limit;

            line(UIAxes, [axisRange(1) axisRange(2)], [0 0], [0 0], LineWidth=2, Color='red')
            line(UIAxes, [0 0], [0 0], [axisRange(1) axisRange(2)], LineWidth=2, Color='green')
            line(UIAxes, [0 0], [axisRange(1) axisRange(2)], [0, 0], LineWidth=2, Color='blue')

            xlabel(UIAxes, 'X-axis', Color='r');
            ylabel(UIAxes, 'Y-axis', Color='b');
            zlabel(UIAxes, 'Z-axis', Color='g');

            % Switch the view to 3D
            view(UIAxes, 3)
            % view(UIAxes, [-801.68 13.13]) % Specific viewing angle
        end

        function h = plot3DGraph(app, UIAxes, vertices, faces, color)
            h = patch(UIAxes, Vertices=vertices, Faces=faces, FaceColor=color, ...
                EdgeColor=app.edge3D);
        end

        function [X, Y, Z] = determine3DShape(~, type)
            switch type
                case 1 % Kubus (Cube)
                    X = [0; 0; 1; 1; 0; 0; 1; 1];
                    Y = [0; 0; 0; 0; 1; 1; 1; 1];
                    Z = [0; 1; 1; 0; 0; 1; 1; 0];
                    
                case 2 % Limas (Square Pyramid)
                    X = [0; 1; 1; 0; 0.5];
                    Y = [0; 0; 1; 1; 0.5];
                    Z = [0; 0; 0; 0; 1];
                    
                case 3 % Tetrahedron
                    X = [0; 1; 0.5; 0.5];
                    Y = [0; 0; sqrt(3)/2; sqrt(3)/6];
                    Z = [0; 0; 0; sqrt(2/3)];
                    
                case 4 % Oktahedron (Octahedron)
                    X = [1; -1; 0; 0; 0; 0];
                    Y = [0; 0; 1; -1; 0; 0];
                    Z = [0; 0; 0; 0; 1; -1];
                    
                case 5 % Prisma Segitiga (Triangular Prism)
                    X = [0; 1; 0.5; 0; 1; 0.5];
                    Y = [0; 0; sqrt(3)/2; 0; 0; sqrt(3)/2];
                    Z = [0; 0; 0; 1; 1; 1];
                    
                case 6 % Prisma Segi Empat (Rectangular Prism)
                    X = [0; 2; 2; 0; 0; 2; 2; 0];
                    Y = [0; 0; 1; 1; 0; 0; 1; 1];
                    Z = [0; 0; 0; 0; 3; 3; 3; 3];
                    
                case 7 % Prisma Heksagonal (Hexagonal Prism)
                    theta = linspace(0, 2*pi, 7); % 6 points + 1 to close the loop
                    X_base = cos(theta);
                    Y_base = sin(theta);
                    X = [X_base, X_base];
                    Y = [Y_base, Y_base];
                    Z = [zeros(1, 7), ones(1, 7)];
                    
                case 8 % Bola (Sphere)
                    [x, y, z] = sphere(20);
                    X = x(:);
                    Y = y(:);
                    Z = z(:);
                    
                case 9 % Kerucut (Cone)
                    [X, Y, Z] = cylinder([1 0], 20);
                    Z = Z * 2;
                    
                case 10 % Tabung (Cylinder)
                    [X, Y, Z] = cylinder(1, 20);
                    Z = Z * 2;
            end
        end

        function [vertices, faces] = generateVerticesFaces(~, x, y, z)
            % Ensure inputs are column vectors
            x = x(:); y = y(:); z = z(:);
            
            % Create vertices by combining x, y, z
            vertices = [x, y, z];
            
            % Generate faces using the convex hull of the points
            faces = convhull(vertices);
        end

        function h = plot3D(app, UIAxes, type, size, start)
            % Get X, Y, Z matrices for the specified shape
            [X, Y, Z] = determine3DShape(app, type);                     

            % Generate the vertices and faces suing convhull
            [vertices, faces] = generateVerticesFaces(app, X, Y, Z);

            % Scale and translate the vertices
            vertices = vertices * size;
            vertices = vertices + start;

            % Save the vertices and faves
            app.Vertices = vertices;
            app.Faces = faces;
            
            % Plot it
            h = plot3DGraph(app, UIAxes, vertices, faces, 'cyan');
        end

        function transformedVertices = calculate3DTransformation(~, vertices, transformationType, transformationValue)
            % validTypes = {'translate', 'rotate', 'reflect', 'dilate'};

            % Prevent transformation error if the coors is not 
            % initialized yet.
            if isempty(vertices)
                transformedVertices = [];
                return
            end    

            transformedVertices = vertices;
            
            switch transformationType
                case 'translate'
                    transformedVertices = transformedVertices + transformationValue;
                    
                case 'rotate'
                    axis = transformationValue{1}; % 'x', 'y', or 'z'
                    angle = transformationValue{2}; % Rotation angle
                    angle_rad = deg2rad(angle);
                    switch axis
                        case 'x'
                            rotationMatrix = [1 0 0; 0 cos(angle_rad) -sin(angle_rad); 0 sin(angle_rad) cos(angle_rad)];
                        case 'y'
                            rotationMatrix = [cos(angle_rad) 0 sin(angle_rad); 0 1 0; -sin(angle_rad) 0 cos(angle_rad)];
                        case 'z'
                            rotationMatrix = [cos(angle_rad) -sin(angle_rad) 0; sin(angle_rad) cos(angle_rad) 0; 0 0 1];
                    end
                    transformedVertices = (rotationMatrix * transformedVertices')';
                    
                case 'reflect'
                    % Across specified plane
                    switch transformationValue
                        case 'xy'
                            M = [1 0 0; 0 1 0; 0 0 -1];
                        case 'yz'
                            M = [-1 0 0; 0 1 0; 0 0 1];
                        case 'xz'
                            M = [1 0 0; 0 -1 0; 0 0 1];
                        otherwise
                    end
                    transformedVertices = (M * transformedVertices')';
                    
                case 'dilate'
                    dilationMatrix = transformationValue * eye(3);
                    transformedVertices = (dilationMatrix * transformedVertices')';
            end
        end

        function h_result = animate3DTransformation(app, UIAxes)
            % Get the shape
            vertices = app.Vertices;
            transformedVertices = app.TransformedVertices;
            faces = app.Faces;
            color = 'red';
            
            % Framerate
            num_steps = 60;
            pause_time = 1/60;
        
            vertex_increment = (transformedVertices - vertices) / num_steps;
            
            for step = 1:num_steps
                currentVertices = vertices + step * vertex_increment;
                                
                h = plot3DGraph(app, UIAxes, currentVertices, faces, color);
                pause(pause_time);
                delete(h)
            end
            
            h_result = plot3DGraph(app, UIAxes, transformedVertices, faces, color);
        end
        
        % Exercise helper
        function generateExerciseQuestion(app)
            app.CurrentIndex = app.CurrentIndex + 1;

            transformations = ["translate", "dilate", "rotate", "reflect"];
            app.Exercise_Answer = transformations(randi(length(transformations)));

            [x, y] = generateRandomShape(app);
            app.Exercise_X_Coor = x;
            app.Exercise_Y_Coor = y;

            app.Exercise_Value = generateRandomTransformationValue(app, app.Exercise_Answer)
        end

        function [x, y] = generateRandomShape(~)
             % Randomly choose the number of sides (3 for triangle, 4 for square, etc.)
            numSides = randi([3, 6]); % Randomly select 3 to 6 sides
            radius = rand() * 2 + 1; % Random radius between 1 and 3 units (ensures it fits -5:5)
            angleOffset = rand() * 2 * pi; % Random rotation of the shape
        
            % Generate angles for the vertices
            angles = linspace(0, 2 * pi, numSides + 1) + angleOffset; % Include closing point
            angles(end) = 2 * pi + angleOffset; % Explicitly close the shape
        
            % Generate coordinates based on the angles and radius
            x = radius * cos(angles);
            y = radius * sin(angles);
        
            % Apply a random translation that ensures the shape fits within -5:5 range
            xOffset = rand() * (5 - radius * 2) * 2 - (5 - radius * 2); % Random x offset
            yOffset = rand() * (5 - radius * 2) * 2 - (5 - radius * 2); % Random y offset
            x = x + xOffset;
            y = y + yOffset;
        end

        function value = generateRandomTransformationValue(app, transformation)
            % Generate a random transformation value based on the type of transformation
            switch transformation
                case "translate"
                    % Translation: Random dx and dy that keep the shape within -5:5
                    dx = rand() * 10 - 5; % Random value between -5 and 5
                    dy = rand() * 10 - 5; % Random value between -5 and 5
                    value = [dx, dy]; % Translation vector [dx, dy]
                
                case "dilate"
                    % Dilation: Random scale factor > 0 
                    scaleFactor = randi([2, 4]); 
                    value = scaleFactor; % Dilation factor
                
                case "rotate"
                    % Rotation: Random angle in degrees
                    validAngles = [-90:-11, 11:90];
                    rotationAngle = validAngles(randi(length(validAngles))); % Random angle between 0 and 360 degrees
                    value = rotationAngle; % Rotation angle
                
                case "reflect"
                    % Reflection: Randomly choose axis ("x", "y", or origin)
                    selectedAxis = reflectionHelper(app, 0, randi([1, 5]));
                    value = selectedAxis; % Axis of reflection
            end
        end

        function calculateExercise(app) 
            [app.Exercise_X_Coor_Trans, app.Exercise_Y_Coor_Trans] = calculate2DTrasnformation(app, ...
                app.Exercise_X_Coor, ...
                app.Exercise_Y_Coor, ...
                app.Exercise_Answer, ...
                app.Exercise_Value);
        end

        function recordAnswer(app, userAnswer)
            correctAnswer = '';
            switch app.Exercise_Answer
                case "translate"
                    correctAnswer = 'TRANSLASI';
                
                case "dilate"
                    correctAnswer = 'DILATASI';
                
                case "rotate"
                    correctAnswer = 'ROTASI';
                
                case "reflect"
                    correctAnswer = 'REFLEKSI';
                
            end

            app.Answers{end + 1, 1} = correctAnswer; 
            app.Answers{end, 2} = userAnswer; 
        end

        function claculateScore(app) 
            totalCorrect = 0;

            for i = 1:size(app.Answers, 1)
                correctAnswer = app.Answers(i, 1);
                userAnswer = app.Answers(i, 2);

                if strcmp(correctAnswer, userAnswer)
                    totalCorrect = totalCorrect + 1;
                end
            end

            app.Score = totalCorrect / app.MaxExercise * 100;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Back(app)
            
        end

        % Button pushed function: DL2D_BackButton, DL3D_BackButton, 
        % ...and 7 other components
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PreviousTab;
        end

        % Button pushed function: DL_BackButton, HSL_BackButton, 
        % ...and 3 other components
        function BackMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MenuTab;
        end

        % Button pushed function: TR_Button
        function TR_ButtonPushed(app, event)
            changeTab(app, app.TRTab)
        end

        % Button pushed function: DL_Button
        function DL_ButtonPushed(app, event)
            changeTab(app, app.DLTab)
        end

        % Button pushed function: RT_Button
        function RT_ButtonPushed(app, event)
            changeTab(app, app.RTTab)
        end

        % Button pushed function: RF_Button
        function RF_ButtonPushed(app, event)
            changeTab(app, app.RFTab)
        end

        % Button pushed function: LTTP_Button
        function LTTP_ButtonPushed(app, event)
            app.CurrentIndex = 1;
            app.IndexLabel.Text = "1";
            app.TotalLabel.Text = string(app.MaxExercise);
            app.Answers = {};
            app.Score = 0;
            delete(app.Exercise_Shape)
            delete(app.Exercise_Result)
            delete(app.Exercise_SLabels)
            delete(app.Exercise_RLabels)
            
            generateExerciseQuestion(app)
            calculateExercise(app)
            
            init2DGraph(app, app.LTTP_UIAxes)
            
            changeTab(app, app.LTTPTab)

            [app.Exercise_Shape, app.Exercise_SLabels] = plot2DGraph(app, app.LTTP_UIAxes, ...
                app.Exercise_X_Coor, app.Exercise_Y_Coor, 'blue', true, false);
            [app.Exercise_Result, app.Exercise_RLabels] = animate2DTransformation(app, app.LTTP_UIAxes, ...
                app.Exercise_X_Coor, app.Exercise_Y_Coor, ...
                app.Exercise_X_Coor_Trans, app.Exercise_Y_Coor_Trans);
        end

        % Button pushed function: TR_Visual2DButton
        function TR_Visual2DButtonPushed(app, event)
            init2DGraph(app, app.TR2D_UIAxes);
            changeTab(app, app.TR2DTab)
        end

        % Button pushed function: TR_Visual3DButton
        function TR_Visual3DButtonPushed(app, event)
            init3dGraph(app, app.TR3D_UIAxes)
            changeTab(app, app.TR3DTab)
        end

        % Button pushed function: DL_Visual2DButton
        function DL_Visual2DButtonPushed(app, event)
            init2DGraph(app, app.DL2D_UIAxes); 
            changeTab(app, app.DL2DTab)
        end

        % Button pushed function: DL_Visual3DButton
        function DL_Visual3DButtonPushed(app, event)
            init3dGraph(app, app.DL3D_UIAxes)
            changeTab(app, app.DL3DTab)
        end

        % Button pushed function: RT_Visual2DButton
        function RT_Visual2DButtonPushed(app, event)
            init2DGraph(app, app.RT2D_UIAxes); 
            changeTab(app, app.RT2DTab)
        end

        % Button pushed function: RT_Visual3DButton
        function RT_Visual3DButtonPushed(app, event)
            init3dGraph(app, app.RT3D_UIAxes)
            changeTab(app, app.RT3DTab)
        end

        % Button pushed function: RF_Visual2DButton
        function RF_Visual2DButtonPushed(app, event)
            init2DGraph(app, app.RF2D_UIAxes); 
            changeTab(app, app.RF2DTab)
        end

        % Button pushed function: RF_Visual3DButton
        function RF_Visual3DButtonPushed(app, event)
            init3dGraph(app, app.RF3D_UIAxes)
            changeTab(app, app.RF3DTab)
        end

        % Button pushed function: TR2D_PlotButton
        function TR2D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.TR2D_SLabels)
            delete(app.TR2D_Shape)
            
            % Set the plot shape coordinates form editField
            app.TR2D_X_Coor = [
                app.TR2D_AX_EditField.Value, app.TR2D_BX_EditField.Value, ...
                app.TR2D_CX_EditField.Value, app.TR2D_DX_EditField.Value, ...
                app.TR2D_EX_EditField.Value, app.TR2D_FX_EditField.Value, ...
                app.TR2D_AX_EditField.Value];


            app.TR2D_Y_Coor = [
                app.TR2D_AY_EditField.Value, app.TR2D_BY_EditField.Value, ...
                app.TR2D_CY_EditField.Value, app.TR2D_DY_EditField.Value, ...
                app.TR2D_EY_EditField.Value, app.TR2D_FY_EditField.Value, ...
                app.TR2D_AY_EditField.Value];

            % Plot the shape into the graph
            [app.TR2D_Shape, app.TR2D_SLabels] = plot2DGraph(app, ...
                app.TR2D_UIAxes, app.TR2D_X_Coor, app.TR2D_Y_Coor, 'blue', true, false);
        end

        % Button pushed function: TR2D_PlayButton
        function TR2D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete previous shape
            delete(app.TR2D_RLabels)
            delete(app.TR2D_Result)
            
            % Calculate the transformed shape coordinates
            [app.TR2D_X_Coor_Trans, app.TR2D_Y_Coor_Trans] = calculate2DTrasnformation( ...
                app, app.TR2D_X_Coor, app.TR2D_Y_Coor, 'translate', ...
                [app.TR2D_X_EditField.Value, app.TR2D_Y_EditField.Value]);

            % Animate the shape
            [app.TR2D_Result, app.TR2D_RLabels] = animate2DTransformation(app, app.TR2D_UIAxes, ...
                app.TR2D_X_Coor, app.TR2D_Y_Coor, app.TR2D_X_Coor_Trans, ...
                app.TR2D_Y_Coor_Trans);
        end

        % Button pushed function: DL2D_PlotButton
        function DL2D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.DL2D_Shape)
            delete(app.DL2D_SLabels)
            
            % Set the plot shape coordinates form editField
            app.DL2D_X_Coor = [
                app.DL2D_AX_EditField.Value, app.DL2D_BX_EditField.Value, ...
                app.DL2D_CX_EditField.Value, app.DL2D_DX_EditField.Value, ...
                app.DL2D_EX_EditField.Value, app.DL2D_FX_EditField.Value, ...
                app.DL2D_AX_EditField.Value];
        
            app.DL2D_Y_Coor = [
                app.DL2D_AY_EditField.Value, app.DL2D_BY_EditField.Value, ...
                app.DL2D_CY_EditField.Value, app.DL2D_DY_EditField.Value, ...
                app.DL2D_EY_EditField.Value, app.DL2D_FY_EditField.Value, ...
                app.DL2D_AY_EditField.Value];
        
            % Plot the shape into the graph
            [app.DL2D_Shape, app.DL2D_SLabels] = plot2DGraph(app, ...
                app.DL2D_UIAxes, app.DL2D_X_Coor, app.DL2D_Y_Coor, 'blue', true, false);
        end

        % Button pushed function: DL2D_PlayButton
        function DL2D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete previous shape
            delete(app.DL2D_Result)
            delete(app.DL2D_RLabels)
            
            % Calculate the transformed shape coordinates
            [app.DL2D_X_Coor_Trans, app.DL2D_Y_Coor_Trans] = calculate2DTrasnformation( ...
                app, app.DL2D_X_Coor, app.DL2D_Y_Coor, 'dilate', app.DL2D_DL_EditField.Value);

            % Animate the shape
            [app.DL2D_Result, app.DL2D_RLabels] = animate2DTransformation(app, app.DL2D_UIAxes, ...
                app.DL2D_X_Coor, app.DL2D_Y_Coor, ...
                app.DL2D_X_Coor_Trans, app.DL2D_Y_Coor_Trans);
        end

        % Button pushed function: RT2D_PlotButton
        function RT2D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.RT2D_Shape)
            delete(app.RT2D_SLabels)
            
            % Set the plot shape coordinates form editField
            app.RT2D_X_Coor = [
                app.RT2D_AX_EditField.Value, app.RT2D_BX_EditField.Value, ...
                app.RT2D_CX_EditField.Value, app.RT2D_DX_EditField.Value, ...
                app.RT2D_EX_EditField.Value, app.RT2D_FX_EditField.Value, ...
                app.RT2D_AX_EditField.Value];
        
            app.RT2D_Y_Coor = [
                app.RT2D_AY_EditField.Value, app.RT2D_BY_EditField.Value, ...
                app.RT2D_CY_EditField.Value, app.RT2D_DY_EditField.Value, ...
                app.RT2D_EY_EditField.Value, app.RT2D_FY_EditField.Value, ...
                app.RT2D_AY_EditField.Value];
        
            % Plot the shape into the graph
            [app.RT2D_Shape, app.RT2D_SLabels] = plot2DGraph(app, app.RT2D_UIAxes, app.RT2D_X_Coor, app.RT2D_Y_Coor, 'blue', true, false);
        end

        % Button pushed function: RT2D_PlayButton
        function RT2D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete previous shape
            delete(app.RT2D_Result)
            delete(app.RT2D_RLabels)
            
            % Calculate the transformed shape coordinates
            [app.RT2D_X_Coor_Trans, app.RT2D_Y_Coor_Trans] = calculate2DTrasnformation( ...
                app, app.RT2D_X_Coor, app.RT2D_Y_Coor, 'rotate', app.RT2D_RT_EditField.Value);

            % Animate the shape
            [app.RT2D_Result, app.RT2D_RLabels] = animate2DTransformation(app, app.RT2D_UIAxes, ...
                app.RT2D_X_Coor, app.RT2D_Y_Coor, ...
                app.RT2D_X_Coor_Trans, app.RT2D_Y_Coor_Trans);
        end

        % Button pushed function: RF2D_PlotButton
        function RF2D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.RF2D_Shape)
            delete(app.RF2D_SLabels)
            
            % Set the plot shape coordinates form editField
            app.RF2D_X_Coor = [
                app.RF2D_AX_EditField.Value, app.RF2D_BX_EditField.Value, ...
                app.RF2D_CX_EditField.Value, app.RF2D_DX_EditField.Value, ...
                app.RF2D_EX_EditField.Value, app.RF2D_FX_EditField.Value, ...
                app.RF2D_AX_EditField.Value];
        
            app.RF2D_Y_Coor = [
                app.RF2D_AY_EditField.Value, app.RF2D_BY_EditField.Value, ...
                app.RF2D_CY_EditField.Value, app.RF2D_DY_EditField.Value, ...
                app.RF2D_EY_EditField.Value, app.RF2D_FY_EditField.Value, ...
                app.RF2D_AY_EditField.Value];
        
            % Plot the shape into the graph
            [app.RF2D_Shape, app.RF2D_SLabels] = plot2DGraph(app, app.RF2D_UIAxes, app.RF2D_X_Coor, app.RF2D_Y_Coor, 'blue', true, false);
        end

        % Button pushed function: RF2D_PlayButton
        function RF2D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete previous shape and line
            delete(app.RF2D_Result)
            delete(app.RF2D_RLabels)
            delete(app.RF2D_Line)

            % Get the value and plot line
            value = reflectionHelper(app, app.RF2D_K_EditField.Value, ...
                app.RF2D_TypeListBox.ValueIndex);

            % Calculate the transformed shape coordinates
            [app.RF2D_X_Coor_Trans, app.RF2D_Y_Coor_Trans] = calculate2DTrasnformation( ...
                app, app.RF2D_X_Coor, app.RF2D_Y_Coor, 'reflect', value);

            % Animate the shape
            [app.RF2D_Result, app.RF2D_RLabels] = animate2DTransformation(app, app.RF2D_UIAxes, ...
                app.RF2D_X_Coor, app.RF2D_Y_Coor, ...
                app.RF2D_X_Coor_Trans, app.RF2D_Y_Coor_Trans);
        end

        % Button pushed function: TR3D_PlotButton
        function TR3D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.Shape_3D)

            % Get the values
            type = app.TR3D_ShapeListBox.ValueIndex;
            size = app.TR3D_SizeSlider.Value;
            start = [app.TR3D_XPos_EditField.Value, app.TR3D_YPos_EditField.Value, ...
                app.TR3D_ZPos_EditField.Value];

            % Prefent scaling error
            if size == 0
                size = 0.01;
            end

            % Plot the shape
            app.Shape_3D = plot3D(app, app.TR3D_UIAxes, type, size, start);
        end

        % Button pushed function: TR3D_PlayButton
        function TR3D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete the previous shape
            delete(app.Result_3D)

            % Calculate the transformed shape vertices
            app.TransformedVertices = calculate3DTransformation(app, ...
                app.Vertices, 'translate', [app.TR3D_X_EditField.Value, ...
                app.TR3D_Y_EditField.Value, app.TR3D_Z_EditField.Value]);

            % Animate the result
            app.Result_3D = animate3DTransformation(app, app.TR3D_UIAxes);
        end

        % Button pushed function: DL3D_PlotButton
        function DL3D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.Shape_3D)

            % Get the values
            type = app.DL3D_ShapeListBox.ValueIndex;
            size = app.DL3D_SizeSlider.Value;
            start = [app.DL3D_XPos_EditField.Value, app.DL3D_YPos_EditField.Value, ...
                app.DL3D_ZPos_EditField.Value];

            % Prefent scaling error
            if size == 0
                size = 0.01;
            end

            % Plot the shape
            app.Shape_3D = plot3D(app, app.DL3D_UIAxes, type, size, start);
        end

        % Button pushed function: DL3D_PlayButton
        function DL3D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete the previous shape
            delete(app.Result_3D)

            % Calculate the transformed shape vertices
            app.TransformedVertices = calculate3DTransformation(app, ...
                app.Vertices, 'dilate', app.DL3D_DL_EditField.Value);

            % Animate the result
            app.Result_3D = animate3DTransformation(app, app.DL3D_UIAxes);
        end

        % Button pushed function: RT3D_PlotButton
        function RT3D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.Shape_3D)

            % Get the values
            type = app.RT3D_ShapeListBox.ValueIndex;
            size = app.RT3D_SizeSlider.Value;
            start = [app.RT3D_XPos_EditField.Value, app.RT3D_YPos_EditField.Value, ...
                app.RT3D_ZPos_EditField.Value];

            % Prefent scaling error
            if size == 0
                size = 0.01;
            end

            % Plot the shape
            app.Shape_3D = plot3D(app, app.RT3D_UIAxes, type, size, start);
        end

        % Button pushed function: RT3D_PlayButton
        function RT3D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete the previous shape
            delete(app.Result_3D)

            % Get the values
            angle = app.RT3D_RT_EditField.Value;
            if app.RT3D_XButton.Value
                axis = 'x';
            elseif app.RT3D_YButton.Value
                axis = 'y';
            elseif app.RT3D_ZButton.Value
                axis = 'z';
            end

            % Calculate the transformed shape vertices
            app.TransformedVertices = calculate3DTransformation(app, ...
                app.Vertices, 'rotate', {axis, angle});

            % Animate the result
            app.Result_3D = animate3DTransformation(app, app.RT3D_UIAxes);
        end

        % Button pushed function: RF3D_PlotButton
        function RF3D_PlotButtonPushed(app, event)
            % Delete the previous shape
            delete(app.Shape_3D)

            % Get the values
            type = app.RF3D_ShapeListBox.ValueIndex;
            size = app.RF3D_SizeSlider.Value;
            start = [app.RF3D_XPos_EditField.Value, app.RF3D_YPos_EditField.Value, ...
                app.RF3D_ZPos_EditField.Value];

            % Prefent scaling error
            if size == 0
                size = 0.01;
            end

            % Plot the shape
            app.Shape_3D = plot3D(app, app.RF3D_UIAxes, type, size, start);
        end

        % Button pushed function: RF3D_PlayButton
        function RF3D_PlayButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            % Delete the previous shape
            delete(app.Result_3D)

            % Get the values
            if app.RF3D_XYButton.Value
                axis = 'xy';
            elseif app.RF3D_YZButton.Value
                axis = 'yz';
            elseif app.RF3D_XZButton.Value
                axis = 'xz';
            end

            % Calculate the transformed shape vertices
            app.TransformedVertices = calculate3DTransformation(app, ...
                app.Vertices, 'reflect', axis);

            % Animate the result
            app.Result_3D = animate3DTransformation(app, app.RF3D_UIAxes);
        end

        % Value changed function: GarisobjekButton
        function GarisobjekButtonValueChanged(app, event)
            value = app.GarisobjekButton.Value;
            if value
                app.edge3D = 'black';
            else
                app.edge3D = 'none';
            end
        end

        % Value changed function: GarisobjekButton_2
        function GarisobjekButton_2ValueChanged(app, event)
            value = app.GarisobjekButton_2.Value;
            if value
                app.edge3D = 'black';
            else
                app.edge3D = 'none';
            end
        end

        % Value changed function: GarisobjekButton_3
        function GarisobjekButton_3ValueChanged(app, event)
            value = app.GarisobjekButton_3.Value;
            if value
                app.edge3D = 'black';
            else
                app.edge3D = 'none';
            end
        end

        % Value changed function: GarisobjekButton_4
        function GarisobjekButton_4ValueChanged(app, event)
            value = app.GarisobjekButton_4.Value;
            if value
                app.edge3D = 'black';
            else
                app.edge3D = 'none';
            end
        end

        % Button pushed function: LTTP_SubmitButton
        function LTTP_SubmitButtonPushed(app, event)
            if (app.isAnimating)
                return
            end
            
            userAnswer = app.LTTP_ShapeListBox.Value;
            recordAnswer(app, userAnswer)

            if (app.CurrentIndex > app.MaxExercise)
                claculateScore(app)
                
                app.ScoreLabel.Text = string(app.Score);
                formattedText = ""; % Initialize an empty string
                for i = 1:size(app.Answers, 1)
                    formattedText = sprintf("%s%d. %s / %s\n", formattedText, i, app.Answers{i, 1}, app.Answers{i, 2});
                end
                app.AnswerTextArea.Value = formattedText;

                changeTab(app, app.HSLTab)
            else
                delete(app.Exercise_Shape)
                delete(app.Exercise_Result)
                delete(app.Exercise_SLabels)
                delete(app.Exercise_RLabels)
                app.IndexLabel.Text = string(app.CurrentIndex);

                generateExerciseQuestion(app)
                calculateExercise(app)
            
                [app.Exercise_Shape, app.Exercise_SLabels] = plot2DGraph(app, app.LTTP_UIAxes, ...
                    app.Exercise_X_Coor, app.Exercise_Y_Coor, 'blue', true, false);
                [app.Exercise_Result, app.Exercise_RLabels] = animate2DTransformation(app, app.LTTP_UIAxes, ...
                    app.Exercise_X_Coor, app.Exercise_Y_Coor, ...
                    app.Exercise_X_Coor_Trans, app.Exercise_Y_Coor_Trans);
            end
        end

        % Button pushed function: LTTP_RepeatButton
        function LTTP_RepeatButtonPushed(app, event)
            delete(app.Exercise_Result)
            delete(app.Exercise_RLabels)

            [app.Exercise_Result, app.Exercise_RLabels] = animate2DTransformation(app, app.LTTP_UIAxes, ...
                app.Exercise_X_Coor, app.Exercise_Y_Coor, ...
                app.Exercise_X_Coor_Trans, app.Exercise_Y_Coor_Trans);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Menu_Image = uiimage(app.MenuTab);
            app.Menu_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Menu.png');
            app.TR_Button = uibutton(app.MenuTab, 'push');
            app.TR_Button.ButtonPushedFcn = createCallbackFcn(app, @TR_ButtonPushed, true);
            app.TR_Button.Text = 'GO';
            app.DL_Button = uibutton(app.MenuTab, 'push');
            app.DL_Button.ButtonPushedFcn = createCallbackFcn(app, @DL_ButtonPushed, true);
            app.DL_Button.Text = 'GO';
            app.RT_Button = uibutton(app.MenuTab, 'push');
            app.RT_Button.ButtonPushedFcn = createCallbackFcn(app, @RT_ButtonPushed, true);
            app.RT_Button.Text = 'GO';
            app.RF_Button = uibutton(app.MenuTab, 'push');
            app.RF_Button.ButtonPushedFcn = createCallbackFcn(app, @RF_ButtonPushed, true);
            app.RF_Button.Text = 'GO';
            app.LTTP_Button = uibutton(app.MenuTab, 'push');
            app.LTTP_Button.ButtonPushedFcn = createCallbackFcn(app, @LTTP_ButtonPushed, true);
            app.LTTP_Button.Text = 'TIPE';
            app.TRTab = uitab(app.TabGroup);
            app.TRTab.Title = 'TR';
            app.TR_Image = uiimage(app.TRTab);
            app.TR_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Translasi Materi.png');
            app.TR_BackButton = uibutton(app.TRTab, 'push');
            app.TR_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackMenuButtonPushed, true);
            app.TR_BackButton.Text = 'BACK';
            app.TR_Visual2DButton = uibutton(app.TRTab, 'push');
            app.TR_Visual2DButton.ButtonPushedFcn = createCallbackFcn(app, @TR_Visual2DButtonPushed, true);
            app.TR_Visual2DButton.Text = 'VISUAL 2D';
            app.TR_Visual3DButton = uibutton(app.TRTab, 'push');
            app.TR_Visual3DButton.ButtonPushedFcn = createCallbackFcn(app, @TR_Visual3DButtonPushed, true);
            app.TR_Visual3DButton.Text = 'VISUAL 3D';
            app.DLTab = uitab(app.TabGroup);
            app.DLTab.Title = 'DL';
            app.DL_Image = uiimage(app.DLTab);
            app.DL_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Dilatasi Materi.png');
            app.DL_BackButton = uibutton(app.DLTab, 'push');
            app.DL_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackMenuButtonPushed, true);
            app.DL_BackButton.Text = 'BACK';
            app.DL_Visual2DButton = uibutton(app.DLTab, 'push');
            app.DL_Visual2DButton.ButtonPushedFcn = createCallbackFcn(app, @DL_Visual2DButtonPushed, true);
            app.DL_Visual2DButton.Text = 'VISUAL 2D';
            app.DL_Visual3DButton = uibutton(app.DLTab, 'push');
            app.DL_Visual3DButton.ButtonPushedFcn = createCallbackFcn(app, @DL_Visual3DButtonPushed, true);
            app.DL_Visual3DButton.Text = 'VISUAL 3D';
            app.RTTab = uitab(app.TabGroup);
            app.RTTab.Title = 'RT';
            app.RT_Image = uiimage(app.RTTab);
            app.RT_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Rotasi Materi.png');
            app.RT_BackButton = uibutton(app.RTTab, 'push');
            app.RT_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackMenuButtonPushed, true);
            app.RT_BackButton.Text = 'BACK';
            app.RT_Visual2DButton = uibutton(app.RTTab, 'push');
            app.RT_Visual2DButton.ButtonPushedFcn = createCallbackFcn(app, @RT_Visual2DButtonPushed, true);
            app.RT_Visual2DButton.Text = 'VISUAL 2D';
            app.RT_Visual3DButton = uibutton(app.RTTab, 'push');
            app.RT_Visual3DButton.ButtonPushedFcn = createCallbackFcn(app, @RT_Visual3DButtonPushed, true);
            app.RT_Visual3DButton.Text = 'VISUAL 3D';
            app.RFTab = uitab(app.TabGroup);
            app.RFTab.Title = 'RF';
            app.RF_Image = uiimage(app.RFTab);
            app.RF_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Refleksi Materi.png');
            app.RF_BackButton = uibutton(app.RFTab, 'push');
            app.RF_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackMenuButtonPushed, true);
            app.RF_BackButton.Text = 'BACK';
            app.RF_Visual2DButton = uibutton(app.RFTab, 'push');
            app.RF_Visual2DButton.ButtonPushedFcn = createCallbackFcn(app, @RF_Visual2DButtonPushed, true);
            app.RF_Visual2DButton.Text = 'VISUAL 2D';
            app.RF_Visual3DButton = uibutton(app.RFTab, 'push');
            app.RF_Visual3DButton.ButtonPushedFcn = createCallbackFcn(app, @RF_Visual3DButtonPushed, true);
            app.RF_Visual3DButton.Text = 'VISUAL 3D';
            app.TR2DTab = uitab(app.TabGroup);
            app.TR2DTab.Title = 'TR2D';
            app.TR2D_Image = uiimage(app.TR2DTab);
            app.TR2D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Translasi 2D.png');
            app.TR2D_BackButton = uibutton(app.TR2DTab, 'push');
            app.TR2D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.TR2D_BackButton.Text = 'BACK';
            app.TR2D_PlotButton = uibutton(app.TR2DTab, 'push');
            app.TR2D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @TR2D_PlotButtonPushed, true);
            app.TR2D_PlotButton.Text = 'PLOT';
            app.TR2D_PlayButton = uibutton(app.TR2DTab, 'push');
            app.TR2D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @TR2D_PlayButtonPushed, true);
            app.TR2D_PlayButton.Text = 'PLAY';
            app.TR2D_AX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_AX_EditField.AllowEmpty = 'on';
            app.TR2D_AX_EditField.Value = 1;
            app.TR2D_AY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_AY_EditField.AllowEmpty = 'on';
            app.TR2D_AY_EditField.Value = 1;
            app.TR2D_BX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_BX_EditField.AllowEmpty = 'on';
            app.TR2D_BX_EditField.Value = 3;
            app.TR2D_BY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_BY_EditField.AllowEmpty = 'on';
            app.TR2D_BY_EditField.Value = 1;
            app.TR2D_CX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_CX_EditField.AllowEmpty = 'on';
            app.TR2D_CX_EditField.Value = 3;
            app.TR2D_CY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_CY_EditField.AllowEmpty = 'on';
            app.TR2D_CY_EditField.Value = 3;
            app.TR2D_DX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_DX_EditField.AllowEmpty = 'on';
            app.TR2D_DX_EditField.Value = [];
            app.TR2D_DY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_DY_EditField.AllowEmpty = 'on';
            app.TR2D_DY_EditField.Value = [];
            app.TR2D_EX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_EX_EditField.AllowEmpty = 'on';
            app.TR2D_EX_EditField.Value = [];
            app.TR2D_EY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_EY_EditField.AllowEmpty = 'on';
            app.TR2D_EY_EditField.Value = [];
            app.TR2D_FX_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_FX_EditField.AllowEmpty = 'on';
            app.TR2D_FX_EditField.Value = [];
            app.TR2D_FY_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_FY_EditField.AllowEmpty = 'on';
            app.TR2D_FY_EditField.Value = [];
            app.TR2D_X_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_X_EditField.Value = 2;
            app.TR2D_Y_EditField = uieditfield(app.TR2DTab, 'numeric');
            app.TR2D_Y_EditField.Value = 1;
            app.Panel = uipanel(app.TR2DTab);
            app.TR2D_UIAxes = uiaxes(app.Panel);
            title(app.TR2D_UIAxes, 'Diagram Kartesius 2D')
            xlabel(app.TR2D_UIAxes, 'X')
            ylabel(app.TR2D_UIAxes, 'Y')
            zlabel(app.TR2D_UIAxes, 'Z')
            app.TR2D_UIAxes.XLim = [-5 5];
            app.TR2D_UIAxes.YLim = [-5 5];
            app.TR2D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.DL2DTab = uitab(app.TabGroup);
            app.DL2DTab.Title = 'DL2D';
            app.DL2D_Image = uiimage(app.DL2DTab);
            app.DL2D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Dilatasi 2D.png');
            app.DL2D_BackButton = uibutton(app.DL2DTab, 'push');
            app.DL2D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.DL2D_BackButton.Text = 'BACK';
            app.Panel_2 = uipanel(app.DL2DTab);
            app.DL2D_UIAxes = uiaxes(app.Panel_2);
            title(app.DL2D_UIAxes, 'Diagram Kartesius 2D')
            xlabel(app.DL2D_UIAxes, 'X')
            ylabel(app.DL2D_UIAxes, 'Y')
            zlabel(app.DL2D_UIAxes, 'Z')
            app.DL2D_UIAxes.XLim = [-5 5];
            app.DL2D_UIAxes.YLim = [-5 5];
            app.DL2D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.DL2D_PlotButton = uibutton(app.DL2DTab, 'push');
            app.DL2D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @DL2D_PlotButtonPushed, true);
            app.DL2D_PlotButton.Text = 'PLOT';
            app.DL2D_PlayButton = uibutton(app.DL2DTab, 'push');
            app.DL2D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @DL2D_PlayButtonPushed, true);
            app.DL2D_PlayButton.Text = 'PLAY';
            app.DL2D_AX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_AX_EditField.AllowEmpty = 'on';
            app.DL2D_AX_EditField.Value = 1;
            app.DL2D_AY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_AY_EditField.AllowEmpty = 'on';
            app.DL2D_AY_EditField.Value = 1;
            app.DL2D_BX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_BX_EditField.AllowEmpty = 'on';
            app.DL2D_BX_EditField.Value = 3;
            app.DL2D_BY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_BY_EditField.AllowEmpty = 'on';
            app.DL2D_BY_EditField.Value = 1;
            app.DL2D_CX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_CX_EditField.AllowEmpty = 'on';
            app.DL2D_CX_EditField.Value = 3;
            app.DL2D_CY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_CY_EditField.AllowEmpty = 'on';
            app.DL2D_CY_EditField.Value = 3;
            app.DL2D_DX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_DX_EditField.AllowEmpty = 'on';
            app.DL2D_DX_EditField.Value = [];
            app.DL2D_DY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_DY_EditField.AllowEmpty = 'on';
            app.DL2D_DY_EditField.Value = [];
            app.DL2D_EX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_EX_EditField.AllowEmpty = 'on';
            app.DL2D_EX_EditField.Value = [];
            app.DL2D_EY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_EY_EditField.AllowEmpty = 'on';
            app.DL2D_EY_EditField.Value = [];
            app.DL2D_FX_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_FX_EditField.AllowEmpty = 'on';
            app.DL2D_FX_EditField.Value = [];
            app.DL2D_FY_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_FY_EditField.AllowEmpty = 'on';
            app.DL2D_FY_EditField.Value = [];
            app.DL2D_DL_EditField = uieditfield(app.DL2DTab, 'numeric');
            app.DL2D_DL_EditField.Value = 2;
            app.RT2DTab = uitab(app.TabGroup);
            app.RT2DTab.Title = 'RT2D';
            app.RT2D_Image = uiimage(app.RT2DTab);
            app.RT2D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Rotasi 2D.png');
            app.RT2D_BackButton = uibutton(app.RT2DTab, 'push');
            app.RT2D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.RT2D_BackButton.Text = 'BACK';
            app.Panel_3 = uipanel(app.RT2DTab);
            app.RT2D_UIAxes = uiaxes(app.Panel_3);
            title(app.RT2D_UIAxes, 'Diagram Kartesius 2D')
            xlabel(app.RT2D_UIAxes, 'X')
            ylabel(app.RT2D_UIAxes, 'Y')
            zlabel(app.RT2D_UIAxes, 'Z')
            app.RT2D_UIAxes.XLim = [-5 5];
            app.RT2D_UIAxes.YLim = [-5 5];
            app.RT2D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RT2D_PlayButton = uibutton(app.RT2DTab, 'push');
            app.RT2D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @RT2D_PlayButtonPushed, true);
            app.RT2D_PlayButton.Text = 'PLAY';
            app.RT2D_PlotButton = uibutton(app.RT2DTab, 'push');
            app.RT2D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @RT2D_PlotButtonPushed, true);
            app.RT2D_PlotButton.Text = 'PLOT';
            app.RT2D_AX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_AX_EditField.AllowEmpty = 'on';
            app.RT2D_AX_EditField.Value = 1;
            app.RT2D_AY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_AY_EditField.AllowEmpty = 'on';
            app.RT2D_AY_EditField.Value = 1;
            app.RT2D_BX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_BX_EditField.AllowEmpty = 'on';
            app.RT2D_BX_EditField.Value = 3;
            app.RT2D_BY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_BY_EditField.AllowEmpty = 'on';
            app.RT2D_BY_EditField.Value = 1;
            app.RT2D_CX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_CX_EditField.AllowEmpty = 'on';
            app.RT2D_CX_EditField.Value = 3;
            app.RT2D_CY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_CY_EditField.AllowEmpty = 'on';
            app.RT2D_CY_EditField.Value = 3;
            app.RT2D_DX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_DX_EditField.AllowEmpty = 'on';
            app.RT2D_DX_EditField.Value = [];
            app.RT2D_DY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_DY_EditField.AllowEmpty = 'on';
            app.RT2D_DY_EditField.Value = [];
            app.RT2D_EX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_EX_EditField.AllowEmpty = 'on';
            app.RT2D_EX_EditField.Value = [];
            app.RT2D_EY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_EY_EditField.AllowEmpty = 'on';
            app.RT2D_EY_EditField.Value = [];
            app.RT2D_FX_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_FX_EditField.AllowEmpty = 'on';
            app.RT2D_FX_EditField.Value = [];
            app.RT2D_FY_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_FY_EditField.AllowEmpty = 'on';
            app.RT2D_FY_EditField.Value = [];
            app.RT2D_RT_EditField = uieditfield(app.RT2DTab, 'numeric');
            app.RT2D_RT_EditField.Value = 45;
            app.RF2DTab = uitab(app.TabGroup);
            app.RF2DTab.Title = 'RF2D';
            app.RF2D_Image = uiimage(app.RF2DTab);
            app.RF2D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Refleksi 2D.png');
            app.RF2D_BackButton = uibutton(app.RF2DTab, 'push');
            app.RF2D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.RF2D_BackButton.Text = 'BACK';
            app.Panel_4 = uipanel(app.RF2DTab);
            app.RF2D_UIAxes = uiaxes(app.Panel_4);
            title(app.RF2D_UIAxes, 'Diagram Kartesius 2D')
            xlabel(app.RF2D_UIAxes, 'X')
            ylabel(app.RF2D_UIAxes, 'Y')
            zlabel(app.RF2D_UIAxes, 'Z')
            app.RF2D_UIAxes.XLim = [-5 5];
            app.RF2D_UIAxes.YLim = [-5 5];
            app.RF2D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RF2D_PlayButton = uibutton(app.RF2DTab, 'push');
            app.RF2D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @RF2D_PlayButtonPushed, true);
            app.RF2D_PlayButton.Text = 'PLAY';
            app.RF2D_PlotButton = uibutton(app.RF2DTab, 'push');
            app.RF2D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @RF2D_PlotButtonPushed, true);
            app.RF2D_PlotButton.Text = 'PLOT';
            app.RF2D_AX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_AX_EditField.AllowEmpty = 'on';
            app.RF2D_AX_EditField.Value = 1;
            app.RF2D_AY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_AY_EditField.AllowEmpty = 'on';
            app.RF2D_AY_EditField.Value = 1;
            app.RF2D_BX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_BX_EditField.AllowEmpty = 'on';
            app.RF2D_BX_EditField.Value = 3;
            app.RF2D_BY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_BY_EditField.AllowEmpty = 'on';
            app.RF2D_BY_EditField.Value = 1;
            app.RF2D_CX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_CX_EditField.AllowEmpty = 'on';
            app.RF2D_CX_EditField.Value = 3;
            app.RF2D_CY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_CY_EditField.AllowEmpty = 'on';
            app.RF2D_CY_EditField.Value = 3;
            app.RF2D_DX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_DX_EditField.AllowEmpty = 'on';
            app.RF2D_DX_EditField.Value = [];
            app.RF2D_DY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_DY_EditField.AllowEmpty = 'on';
            app.RF2D_DY_EditField.Value = [];
            app.RF2D_EX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_EX_EditField.AllowEmpty = 'on';
            app.RF2D_EX_EditField.Value = [];
            app.RF2D_EY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_EY_EditField.AllowEmpty = 'on';
            app.RF2D_EY_EditField.Value = [];
            app.RF2D_FX_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_FX_EditField.AllowEmpty = 'on';
            app.RF2D_FX_EditField.Value = [];
            app.RF2D_FY_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_FY_EditField.AllowEmpty = 'on';
            app.RF2D_FY_EditField.Value = [];
            app.RF2D_TypeListBox = uilistbox(app.RF2DTab);
            app.RF2D_TypeListBox.Items = {'titik awal', 'sumbu x', 'sumbu y', 'garis y=x', 'garis y=-x', 'sumbu x=k', 'sumbu y=k'};
            app.RF2D_TypeListBox.Value = 'titik awal';
            app.RF2D_K_EditField = uieditfield(app.RF2DTab, 'numeric');
            app.RF2D_K_EditField.Value = 1;
            app.TR3DTab = uitab(app.TabGroup);
            app.TR3DTab.Title = 'TR3D';
            app.TR3D_Image = uiimage(app.TR3DTab);
            app.TR3D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Translasi 3D.png');
            app.TR3D_BackButton = uibutton(app.TR3DTab, 'push');
            app.TR3D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.TR3D_BackButton.Text = 'BACK';
            app.Panel_5 = uipanel(app.TR3DTab);
            app.TR3D_UIAxes = uiaxes(app.Panel_5);
            title(app.TR3D_UIAxes, 'Diagram Kartesius 3D')
            xlabel(app.TR3D_UIAxes, 'X')
            ylabel(app.TR3D_UIAxes, 'Y')
            zlabel(app.TR3D_UIAxes, 'Z')
            app.TR3D_UIAxes.XLim = [-5 5];
            app.TR3D_UIAxes.YLim = [-5 5];
            app.TR3D_UIAxes.ZLim = [-5 5];
            app.TR3D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.TR3D_UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.TR3D_PlotButton = uibutton(app.TR3DTab, 'push');
            app.TR3D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @TR3D_PlotButtonPushed, true);
            app.TR3D_PlotButton.Text = 'PLOT';
            app.TR3D_PlayButton = uibutton(app.TR3DTab, 'push');
            app.TR3D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @TR3D_PlayButtonPushed, true);
            app.TR3D_PlayButton.Text = 'PLAY';
            app.TR3D_ShapeListBox = uilistbox(app.TR3DTab);
            app.TR3D_ShapeListBox.Items = {'Kubus', 'Limas', 'Tetrahedron', 'Oktahedron', 'Prisma segitiga', 'Prisma segi empat', 'Prisma Heksagonal', 'Bola', 'Kerucut', 'Tabung'};
            app.TR3D_ShapeListBox.Value = 'Kubus';
            app.TR3D_SizeSlider = uislider(app.TR3DTab);
            app.TR3D_SizeSlider.Limits = [0 10];
            app.TR3D_SizeSlider.Value = 1;
            app.TR3D_XPos_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_XPos_EditField.Value = 1;
            app.TR3D_YPos_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_YPos_EditField.Value = 1;
            app.TR3D_ZPos_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_ZPos_EditField.Value = 1;
            app.TR3D_X_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_X_EditField.Value = 2;
            app.TR3D_Y_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_Y_EditField.Value = 2;
            app.TR3D_Z_EditField = uieditfield(app.TR3DTab, 'numeric');
            app.TR3D_Z_EditField.Value = 2;
            app.GarisobjekButton = uibutton(app.TR3DTab, 'state');
            app.GarisobjekButton.ValueChangedFcn = createCallbackFcn(app, @GarisobjekButtonValueChanged, true);
            app.GarisobjekButton.Text = 'Garis objek';
            app.DL3DTab = uitab(app.TabGroup);
            app.DL3DTab.Title = 'DL3D';
            app.DL3D_Image = uiimage(app.DL3DTab);
            app.DL3D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Dilatasi 3D.png');
            app.DL3D_BackButton = uibutton(app.DL3DTab, 'push');
            app.DL3D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.DL3D_BackButton.Text = 'BACK';
            app.Panel_6 = uipanel(app.DL3DTab);
            app.DL3D_UIAxes = uiaxes(app.Panel_6);
            title(app.DL3D_UIAxes, 'Diagram Kartesius 3D')
            xlabel(app.DL3D_UIAxes, 'X')
            ylabel(app.DL3D_UIAxes, 'Y')
            zlabel(app.DL3D_UIAxes, 'Z')
            app.DL3D_UIAxes.XLim = [-5 5];
            app.DL3D_UIAxes.YLim = [-5 5];
            app.DL3D_UIAxes.ZLim = [-5 5];
            app.DL3D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.DL3D_UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.DL3D_UIAxes.ZTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.DL3D_PlotButton = uibutton(app.DL3DTab, 'push');
            app.DL3D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @DL3D_PlotButtonPushed, true);
            app.DL3D_PlotButton.Text = 'PLOT';
            app.DL3D_PlayButton = uibutton(app.DL3DTab, 'push');
            app.DL3D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @DL3D_PlayButtonPushed, true);
            app.DL3D_PlayButton.Text = 'PLAY';
            app.DL3D_ShapeListBox = uilistbox(app.DL3DTab);
            app.DL3D_ShapeListBox.Items = {'Kubus', 'Limas', 'Tetrahedron', 'Oktahedron', 'Prisma segitiga', 'Prisma segi empat', 'Prisma Heksagonal', 'Bola', 'Kerucut', 'Tabung'};
            app.DL3D_ShapeListBox.Value = 'Kubus';
            app.DL3D_SizeSlider = uislider(app.DL3DTab);
            app.DL3D_SizeSlider.Limits = [0 10];
            app.DL3D_SizeSlider.Value = 1;
            app.DL3D_YPos_EditField = uieditfield(app.DL3DTab, 'numeric');
            app.DL3D_YPos_EditField.Value = 1;
            app.DL3D_XPos_EditField = uieditfield(app.DL3DTab, 'numeric');
            app.DL3D_XPos_EditField.Value = 1;
            app.DL3D_ZPos_EditField = uieditfield(app.DL3DTab, 'numeric');
            app.DL3D_ZPos_EditField.Value = 1;
            app.DL3D_DL_EditField = uieditfield(app.DL3DTab, 'numeric');
            app.DL3D_DL_EditField.Value = 2;
            app.GarisobjekButton_2 = uibutton(app.DL3DTab, 'state');
            app.GarisobjekButton_2.ValueChangedFcn = createCallbackFcn(app, @GarisobjekButton_2ValueChanged, true);
            app.GarisobjekButton_2.Text = 'Garis objek';
            app.RT3DTab = uitab(app.TabGroup);
            app.RT3DTab.Title = 'RT3D';
            app.RT3D_Image = uiimage(app.RT3DTab);
            app.RT3D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Rotasi 3D.png');
            app.RT3D_BackButton = uibutton(app.RT3DTab, 'push');
            app.RT3D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.RT3D_BackButton.Text = 'BACK';
            app.Panel_7 = uipanel(app.RT3DTab);
            app.RT3D_UIAxes = uiaxes(app.Panel_7);
            title(app.RT3D_UIAxes, 'Diagram Kartesius 3D')
            xlabel(app.RT3D_UIAxes, 'X')
            ylabel(app.RT3D_UIAxes, 'Y')
            zlabel(app.RT3D_UIAxes, 'Z')
            app.RT3D_UIAxes.XLim = [-5 5];
            app.RT3D_UIAxes.YLim = [-5 5];
            app.RT3D_UIAxes.ZLim = [-5 5];
            app.RT3D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RT3D_UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RT3D_UIAxes.ZTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.RT3D_PlotButton = uibutton(app.RT3DTab, 'push');
            app.RT3D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @RT3D_PlotButtonPushed, true);
            app.RT3D_PlotButton.Text = 'PLOT';
            app.RT3D_PlayButton = uibutton(app.RT3DTab, 'push');
            app.RT3D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @RT3D_PlayButtonPushed, true);
            app.RT3D_PlayButton.Text = 'PLAY';
            app.RT3D_ShapeListBox = uilistbox(app.RT3DTab);
            app.RT3D_ShapeListBox.Items = {'Kubus', 'Limas', 'Tetrahedron', 'Oktahedron', 'Prisma segitiga', 'Prisma segi empat', 'Prisma Heksagonal', 'Bola', 'Kerucut', 'Tabung'};
            app.RT3D_ShapeListBox.Value = 'Kubus';
            app.RT3D_SizeSlider = uislider(app.RT3DTab);
            app.RT3D_SizeSlider.Limits = [0 10];
            app.RT3D_SizeSlider.Value = 1;
            app.RT3D_YPos_EditField = uieditfield(app.RT3DTab, 'numeric');
            app.RT3D_YPos_EditField.Value = 1;
            app.RT3D_XPos_EditField = uieditfield(app.RT3DTab, 'numeric');
            app.RT3D_XPos_EditField.Value = 1;
            app.RT3D_ZPos_EditField = uieditfield(app.RT3DTab, 'numeric');
            app.RT3D_ZPos_EditField.Value = 1;
            app.RT3D_ButtonGroup = uibuttongroup(app.RT3DTab);
            app.RT3D_XButton = uiradiobutton(app.RT3D_ButtonGroup);
            app.RT3D_XButton.Text = '';
            app.RT3D_XButton.Value = true;
            app.RT3D_YButton = uiradiobutton(app.RT3D_ButtonGroup);
            app.RT3D_YButton.Text = '';
            app.RT3D_ZButton = uiradiobutton(app.RT3D_ButtonGroup);
            app.RT3D_ZButton.Text = '';
            app.RT3D_RT_EditField = uieditfield(app.RT3DTab, 'numeric');
            app.RT3D_RT_EditField.Value = 90;
            app.GarisobjekButton_3 = uibutton(app.RT3DTab, 'state');
            app.GarisobjekButton_3.ValueChangedFcn = createCallbackFcn(app, @GarisobjekButton_3ValueChanged, true);
            app.GarisobjekButton_3.Text = 'Garis objek';
            app.RF3DTab = uitab(app.TabGroup);
            app.RF3DTab.Title = 'RF3D';
            app.RF3D_Image = uiimage(app.RF3DTab);
            app.RF3D_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Refleksi 3D.png');
            app.RF3D_BackButton = uibutton(app.RF3DTab, 'push');
            app.RF3D_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.RF3D_BackButton.Text = 'BACK';
            app.Panel_8 = uipanel(app.RF3DTab);
            app.RF3D_UIAxes = uiaxes(app.Panel_8);
            title(app.RF3D_UIAxes, 'Diagram Kartesius 3D')
            xlabel(app.RF3D_UIAxes, 'X')
            ylabel(app.RF3D_UIAxes, 'Y')
            zlabel(app.RF3D_UIAxes, 'Z')
            app.RF3D_UIAxes.XLim = [-5 5];
            app.RF3D_UIAxes.YLim = [-5 5];
            app.RF3D_UIAxes.ZLim = [-5 5];
            app.RF3D_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RF3D_UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.RF3D_UIAxes.ZTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.RF3D_PlotButton = uibutton(app.RF3DTab, 'push');
            app.RF3D_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @RF3D_PlotButtonPushed, true);
            app.RF3D_PlotButton.Text = 'PLOT';
            app.RF3D_PlayButton = uibutton(app.RF3DTab, 'push');
            app.RF3D_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @RF3D_PlayButtonPushed, true);
            app.RF3D_PlayButton.Text = 'PLAY';
            app.RF3D_ShapeListBox = uilistbox(app.RF3DTab);
            app.RF3D_ShapeListBox.Items = {'Kubus', 'Limas', 'Tetrahedron', 'Oktahedron', 'Prisma segitiga', 'Prisma segi empat', 'Prisma Heksagonal', 'Bola', 'Kerucut', 'Tabung'};
            app.RF3D_ShapeListBox.Value = 'Kubus';
            app.RF3D_SizeSlider = uislider(app.RF3DTab);
            app.RF3D_SizeSlider.Limits = [0 10];
            app.RF3D_SizeSlider.Value = 1;
            app.RF3D_YPos_EditField = uieditfield(app.RF3DTab, 'numeric');
            app.RF3D_YPos_EditField.Value = 1;
            app.RF3D_XPos_EditField = uieditfield(app.RF3DTab, 'numeric');
            app.RF3D_XPos_EditField.Value = 1;
            app.RF3D_ZPos_EditField = uieditfield(app.RF3DTab, 'numeric');
            app.RF3D_ZPos_EditField.Value = 1;
            app.RF3D_ButtonGroup = uibuttongroup(app.RF3DTab);
            app.RF3D_XYButton = uiradiobutton(app.RF3D_ButtonGroup);
            app.RF3D_XYButton.Text = '';
            app.RF3D_XYButton.Value = true;
            app.RF3D_YZButton = uiradiobutton(app.RF3D_ButtonGroup);
            app.RF3D_YZButton.Text = '';
            app.RF3D_XZButton = uiradiobutton(app.RF3D_ButtonGroup);
            app.RF3D_XZButton.Text = '';
            app.GarisobjekButton_4 = uibutton(app.RF3DTab, 'state');
            app.GarisobjekButton_4.ValueChangedFcn = createCallbackFcn(app, @GarisobjekButton_4ValueChanged, true);
            app.GarisobjekButton_4.Text = 'Garis objek';
            app.LTTPTab = uitab(app.TabGroup);
            app.LTTPTab.Title = 'LTTP';
            app.LTTP_Image = uiimage(app.LTTPTab);
            app.LTTP_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Latihan Type.png');
            app.LTTP_BackButton = uibutton(app.LTTPTab, 'push');
            app.LTTP_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.LTTP_BackButton.Text = 'BACK';
            app.LTTP_ShapeListBox = uilistbox(app.LTTPTab);
            app.LTTP_ShapeListBox.Items = {'TRANSLASI', 'DILATASI', 'ROTASI', 'REFLEKSI'};
            app.LTTP_ShapeListBox.Value = 'TRANSLASI';
            app.LTTP_SubmitButton = uibutton(app.LTTPTab, 'push');
            app.LTTP_SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @LTTP_SubmitButtonPushed, true);
            app.LTTP_SubmitButton.Text = 'SUBMIT';
            app.Panel_9 = uipanel(app.LTTPTab);
            app.LTTP_UIAxes = uiaxes(app.Panel_9);
            title(app.LTTP_UIAxes, 'Soal')
            xlabel(app.LTTP_UIAxes, 'X')
            ylabel(app.LTTP_UIAxes, 'Y')
            zlabel(app.LTTP_UIAxes, 'Z')
            app.LTTP_UIAxes.XLim = [-5 5];
            app.LTTP_UIAxes.YLim = [-5 5];
            app.LTTP_UIAxes.ZLim = [-5 5];
            app.LTTP_UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LTTP_UIAxes.XTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.LTTP_UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LTTP_UIAxes.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.LTTP_UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LTTP_UIAxes.ZTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.IndexLabel = uilabel(app.LTTPTab);
            app.IndexLabel.Text = '0';
            app.TotalLabel = uilabel(app.LTTPTab);
            app.TotalLabel.Text = '10';
            app.LTTP_RepeatButton = uibutton(app.LTTPTab, 'push');
            app.LTTP_RepeatButton.ButtonPushedFcn = createCallbackFcn(app, @LTTP_RepeatButtonPushed, true);
            app.LTTP_RepeatButton.Text = 'ULANGI';
            app.HSLTab = uitab(app.TabGroup);
            app.HSLTab.Title = 'HSL';
            app.HSL_Image = uiimage(app.HSLTab);
            app.HSL_Image.ImageSource = fullfile(pathToMLAPP, 'Res', 'Hasil.png');
            app.HSL_BackButton = uibutton(app.HSLTab, 'push');
            app.HSL_BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackMenuButtonPushed, true);
            app.HSL_BackButton.Text = 'BACK';
            app.ScoreLabel = uilabel(app.HSLTab);
            app.ScoreLabel.Text = '10';
            app.AnswerTextArea = uitextarea(app.HSLTab);
            app.AnswerTextArea.Editable = 'off';
            app.AnswerTextArea.Value = {'1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'; '1. Translasi / Rotasi'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geometric_learning_media

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Back)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end